classdef CB_SLREQ_GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                    matlab.ui.Figure
        TabGroup                    matlab.ui.container.TabGroup
        TrackerSelectionTab         matlab.ui.container.Tab
        SavetoprefButton            matlab.ui.control.Button
        DesiredTrackertypePanel     matlab.ui.container.Panel
        ActiveTrackerTypes          matlab.ui.control.ListBox
        ProjectandTrackerselectionPanel  matlab.ui.container.Panel
        ReadItemsButton             matlab.ui.control.Button
        TrackerDropDown             matlab.ui.control.DropDown
        TrackerDropDownLabel        matlab.ui.control.Label
        ProjectDropDown             matlab.ui.control.DropDown
        ProjectDropDownLabel        matlab.ui.control.Label
        LoginDetailsPanel           matlab.ui.container.Panel
        WebServerTextArea           matlab.ui.control.TextArea
        WebServerTextAreaLabel      matlab.ui.control.Label
        RESTServerTextArea          matlab.ui.control.TextArea
        ServerNameLabel             matlab.ui.control.Label
        UserTextArea                matlab.ui.control.TextArea
        UserTextAreaLabel           matlab.ui.control.Label
        PasswordTextArea            matlab.ui.control.TextArea
        PasswordTextAreaLabel       matlab.ui.control.Label
        RequirementSelectionTab     matlab.ui.container.Tab
        AssociationsListBox         matlab.ui.control.ListBox
        AssociationsListBoxLabel    matlab.ui.control.Label
        ExplanatoryTextAreaLabel_2  matlab.ui.control.Label
        ExplanatoryTextArea         matlab.ui.control.TextArea
        ExplanatoryTextAreaLabel    matlab.ui.control.Label
        onEditField                 matlab.ui.control.EditField
        onEditFieldLabel            matlab.ui.control.Label
        SubmittedbyEditField        matlab.ui.control.EditField
        SubmittedbyEditFieldLabel   matlab.ui.control.Label
        VersionEditField            matlab.ui.control.EditField
        VersionEditFieldLabel       matlab.ui.control.Label
        Tree                        matlab.ui.container.Tree
        ReqText                     matlab.ui.control.TextArea
        SeeincbXButton              matlab.ui.control.Button
    end


    properties (Access = private, Hidden)
        urlweb
        url
        username
        password
        jsonOptions
        activeItem
    end
    properties (Hidden)
        access
    end

    methods
        function r = getActiveProject(app)
            r = app.access.activeProject;
        end
        function r = getActiveTracker(app)
            r = app.access.activeTracker;
        end
        function r = getActiveItem(app)
            r = app.activeItem;
        end
        function r = getLinkData(app)
            r = app.access.createLinkData(app.activeItem);
        end
        function showItemInBrowser(app, id)
            web([app.access.urlweb 'issue/' num2str(id)]);
        end
        function r = getActiveProjectURL(app, id)
            r = [app.access.urlweb 'trackerTree/' num2str(id)];
        end
    end


    methods (Access = private)

        function node = makeTreeNode(app, details, root, u)
            node = u;
            if isfield(root, 'children')
                for i=1:numel(root.children)
                    if iscell(root.children)
                        c = root.children{i};
                    else
                        c = root.children(i);
                    end
                    if isfield(c, 'name')
                        n = c.name;
                    else
                        n = 'NA';
                    end
                    x = uitreenode(node, 'Text', n, 'NodeData', c);
                    if isfield(c, 'id')
                        filter = arrayfun(@(x) x.id==c.id, app.access.items.itemRefs);
                        item = details(filter);
                        app.makeTreeNode(details, item{1}, x);
                    end
                end
            end
        end


        function populateProjectList(app)
            % Read the list of the server
            app.access.readProjectList();
            projects = app.access.projects;

            % Populate the list of projects
            app.ProjectDropDown.Items = arrayfun(@(x) string(x.name), projects);
            app.ProjectDropDown.ItemsData = arrayfun(@(x) x.id, projects);
            if any(arrayfun(@(x) x == app.access.activeProject, app.ProjectDropDown.ItemsData))
                app.ProjectDropDown.Value = app.access.activeProject;
            else
                app.access.activeProject = app.ProjectDropDown.ItemsData(1);
                app.ProjectDropDown.Value = app.ProjectDropDown.ItemsData(1);
            end
            app.access.selectProject(app.access.activeProject);
            app.populateTrackerList();
        end

        function populateTrackerList(app)
            % Read the list of the server
            app.access.readTrackerList();
            trackers = app.access.trackers;

            % Populate the tracker list.
            app.TrackerDropDown.Items = arrayfun(@(x) string(x.name), trackers);
            app.TrackerDropDown.ItemsData = arrayfun(@(x) x.id, trackers);
            if any(arrayfun(@(x) x ==app.access.activeTracker, app.TrackerDropDown.ItemsData))
                app.TrackerDropDown.Value = app.access.activeTracker;
            else
                app.access.activeTracker = app.TrackerDropDown.ItemsData(1);
                app.TrackerDropDown.Value = app.TrackerDropDown.ItemsData(1);
            end
            app.access.selectTracker(app.access.activeTracker);
        end

        function populateItems(app)
            % Empty the app.Tree uitreenode
            c = app.Tree.Children;
            c.delete;

            % Read the items from the database
            app.access.readItemList();
            items = app.access.items;

            % Display all items as a tree.
            if isfield(items, 'itemRefs') && ~isempty(items.itemRefs)
                if iscell(items.itemRefs)
                    details = cellfun(@(x) app.access.readItemDetails(x.id), items.itemRefs, 'UniformOutput',false);
                else
                    details = arrayfun(@(x) app.access.readItemDetails(x.id), items.itemRefs, 'UniformOutput',false);
                end
                roots = find(cellfun(@(x) ~isfield(x, 'parent'), details));
                for i = numel(roots):-1:1
                    r = details{roots(i)};
                    try
                        name = r.name;
                    catch
                        name = '?Name not found?';
                    end
                    n = uitreenode(app.Tree,'Text', name);
                    app.makeTreeNode(details, r, n);
                end
            end
            app.TabGroup.SelectedTab = app.TabGroup.Children(2);
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.access = CodeBeamerSwagger;
            app.WebServerTextArea.Value = app.access.urlweb;
            app.RESTServerTextArea.Value = app.access.url;
            app.UserTextArea.Value = app.access.username;
            app.PasswordTextArea.Value = app.access.password;

            % Find all possible items
            itemTypes = app.access.getItemTypes();
            names = arrayfun(@(x) x.name, itemTypes, 'UniformOutput', false);
            ids = arrayfun(@(x) x.id, itemTypes);

            % Find active item types
            activeIds = app.access.activeItemTypeIds;
            app.ActiveTrackerTypes.Items = names;
            app.ActiveTrackerTypes.ItemsData = ids;
            filter= arrayfun(@(x) any(x==activeIds),ids);
            app.ActiveTrackerTypes.Value = ids(filter);

            % If login detail present, let's fill the project dropdwon
            if ~isempty(app.access.url) && ~isempty(app.access.username) &&  ~isempty(app.access.password)
                app.populateProjectList();
            end
        end

        % Value changed function: ProjectDropDown
        function ChooseProject(app, event)
            % Find the dropdown active selection.
            app.access.selectProject(app.ProjectDropDown.Value);

            % Populate the tracker list.
            app.populateTrackerList();

        end

        % Value changed function: TrackerDropDown
        function ChooseTracker(app, event)
            % Look in drop down box what tracker is selected.
            uri = app.TrackerDropDown.Value;
            % Read all items in that tracker.
            app.access.selectTracker(uri);
        end

        % Selection changed function: Tree
        function ItemSelected(app, event)
            % Lookup what item was selected.
            selectedNodes = app.Tree.SelectedNodes;
            app.activeItem = selectedNodes.NodeData;

            % If the selected object has requirement data
            if ~isempty(selectedNodes.NodeData)
                app.activeItem = selectedNodes.NodeData;
                % Read the item details from codebeamer
                details = app.access.readItemDetails(app.activeItem.id);

                %% Fill all the ui fields
                % Description
                app.ReqText.Value = details.description;
                try
                % submitter, submit date, version.
                app.SubmittedbyEditField.Value = details.modifiedBy.name;
                app.onEditField.Value = details.modifiedAt;
                catch
                end
                app.VersionEditField.Value = num2str(details.version);

                % Explanatory text (if present)
                app.ExplanatoryTextArea.Value = CodeBeamerSwagger.getExplanatoryText(details);

                % Link to model associations
                assoc = app.access.readAssociationV1(details.id);
                if ~isempty(assoc) && isfield(assoc, 'url')
                    app.AssociationsListBox.Items = {assoc.url};
                else
                    app.AssociationsListBox.Items = {};
                end
            end
        end

        % Callback function
        function DesignLinksListBoxValueChanged(app, event)
            % Find which requirement link was clicked.
            value = app.DesignLinksListBox.Value;
            % Hilite is in the model
            if getpref('codebeamer','rminavigate', false)
                rmiobjnavigate(value.model, value.sid);
            else
                hilite_system([strrep(value.model, '.slx', ''), value.sid]);
            end
        end

        % Button pushed function: SavetoprefButton
        function SavetoprefButtonPushed(app, event)
            setpref('codebeamer', 'urlweb', app.WebServerTextArea.Value{1});
            setpref('codebeamer', 'url', app.RESTServerTextArea.Value{1});
            setpref('codebeamer', 'username', app.UserTextArea.Value{1});
            setpref('codebeamer', 'password', app.PasswordTextArea.Value{1});
            setpref('codebeamer', 'project', app.access.activeProject);
            setpref('codebeamer', 'tracker', app.access.activeTracker);
            setpref('codebeamer', 'itemTypes', app.ActiveTrackerTypes.Value);
        end

        % Button pushed function: SeeincbXButton
        function SeeincbXButtonPushed(app, event)
            web(['https://cmr-surgical.intland.cloud/x/#/issue/' num2str(app.activeItem.id)]);
        end

        % Value changed function: AssociationsListBox
        function AssociationsListBoxClicked(app, event)
            idmatch = regexp(event.Value,  '\[%22(?<model>.*?).slx%22,%22:(?<sid>[0-9]*)%22\]', 'names');
            hilite_system([strrep(idmatch.model, '.slx', '')  ':' num2str(idmatch.sid)]);
        end

        % Button pushed function: ReadItemsButton
        function ReadItemsButtonPushed(app, event)
            app.populateItems();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.TrackerSelectionTab = uitab(app.TabGroup);
            app.TrackerSelectionTab.Title = 'Tracker Selection';
            app.LoginDetailsPanel = uipanel(app.TrackerSelectionTab);
            app.LoginDetailsPanel.Title = 'Login Details';
            app.PasswordTextAreaLabel = uilabel(app.LoginDetailsPanel);
            app.PasswordTextAreaLabel.Text = 'Password';
            app.PasswordTextArea = uitextarea(app.LoginDetailsPanel);
            app.UserTextAreaLabel = uilabel(app.LoginDetailsPanel);
            app.UserTextAreaLabel.Text = 'User';
            app.UserTextArea = uitextarea(app.LoginDetailsPanel);
            app.ServerNameLabel = uilabel(app.LoginDetailsPanel);
            app.ServerNameLabel.Text = 'REST Server';
            app.RESTServerTextArea = uitextarea(app.LoginDetailsPanel);
            app.WebServerTextAreaLabel = uilabel(app.LoginDetailsPanel);
            app.WebServerTextAreaLabel.Text = 'Web Server';
            app.WebServerTextArea = uitextarea(app.LoginDetailsPanel);
            app.ProjectandTrackerselectionPanel = uipanel(app.TrackerSelectionTab);
            app.ProjectandTrackerselectionPanel.Title = 'Project and Tracker selection';
            app.ProjectDropDownLabel = uilabel(app.ProjectandTrackerselectionPanel);
            app.ProjectDropDownLabel.Text = 'Project';
            app.ProjectDropDown = uidropdown(app.ProjectandTrackerselectionPanel);
            app.ProjectDropDown.Items = {};
            app.ProjectDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseProject, true);
            app.ProjectDropDown.Value = {};
            app.TrackerDropDownLabel = uilabel(app.ProjectandTrackerselectionPanel);
            app.TrackerDropDownLabel.Text = 'Tracker';
            app.TrackerDropDown = uidropdown(app.ProjectandTrackerselectionPanel);
            app.TrackerDropDown.Items = {};
            app.TrackerDropDown.ValueChangedFcn = createCallbackFcn(app, @ChooseTracker, true);
            app.TrackerDropDown.Value = {};
            app.ReadItemsButton = uibutton(app.ProjectandTrackerselectionPanel, 'push');
            app.ReadItemsButton.ButtonPushedFcn = createCallbackFcn(app, @ReadItemsButtonPushed, true);
            app.ReadItemsButton.Text = 'Read Items';
            app.DesiredTrackertypePanel = uipanel(app.TrackerSelectionTab);
            app.DesiredTrackertypePanel.Title = 'Desired Tracker type';
            app.ActiveTrackerTypes = uilistbox(app.DesiredTrackertypePanel);
            app.ActiveTrackerTypes.Items = {'TBD'};
            app.ActiveTrackerTypes.Multiselect = 'on';
            app.ActiveTrackerTypes.Value = {'TBD'};
            app.SavetoprefButton = uibutton(app.TrackerSelectionTab, 'push');
            app.SavetoprefButton.ButtonPushedFcn = createCallbackFcn(app, @SavetoprefButtonPushed, true);
            app.SavetoprefButton.Text = 'Save to pref';
            app.RequirementSelectionTab = uitab(app.TabGroup);
            app.RequirementSelectionTab.Title = 'Requirement Selection';
            app.SeeincbXButton = uibutton(app.RequirementSelectionTab, 'push');
            app.SeeincbXButton.ButtonPushedFcn = createCallbackFcn(app, @SeeincbXButtonPushed, true);
            app.SeeincbXButton.Text = 'See in cbX';
            app.ReqText = uitextarea(app.RequirementSelectionTab);
            app.ReqText.Editable = 'off';
            app.Tree = uitree(app.RequirementSelectionTab);
            app.Tree.SelectionChangedFcn = createCallbackFcn(app, @ItemSelected, true);
            app.VersionEditFieldLabel = uilabel(app.RequirementSelectionTab);
            app.VersionEditFieldLabel.Text = 'Version';
            app.VersionEditField = uieditfield(app.RequirementSelectionTab, 'text');
            app.VersionEditField.Editable = 'off';
            app.SubmittedbyEditFieldLabel = uilabel(app.RequirementSelectionTab);
            app.SubmittedbyEditFieldLabel.Text = 'Submitted by';
            app.SubmittedbyEditField = uieditfield(app.RequirementSelectionTab, 'text');
            app.SubmittedbyEditField.Editable = 'off';
            app.onEditFieldLabel = uilabel(app.RequirementSelectionTab);
            app.onEditFieldLabel.Text = 'on';
            app.onEditField = uieditfield(app.RequirementSelectionTab, 'text');
            app.ExplanatoryTextAreaLabel = uilabel(app.RequirementSelectionTab);
            app.ExplanatoryTextAreaLabel.Text = 'Explanatory';
            app.ExplanatoryTextArea = uitextarea(app.RequirementSelectionTab);
            app.ExplanatoryTextAreaLabel_2 = uilabel(app.RequirementSelectionTab);
            app.ExplanatoryTextAreaLabel_2.Text = 'Description';
            app.AssociationsListBoxLabel = uilabel(app.RequirementSelectionTab);
            app.AssociationsListBoxLabel.Text = 'Associations';
            app.AssociationsListBox = uilistbox(app.RequirementSelectionTab);
            app.AssociationsListBox.Items = {};
            app.AssociationsListBox.ValueChangedFcn = createCallbackFcn(app, @AssociationsListBoxClicked, true);
            app.AssociationsListBox.Value = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CB_SLREQ_GUI

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end